% description: Copyright 2020 - 2026 The MathWorks Inc.
classdef FluorescenceTracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        SetupTab               matlab.ui.container.Tab
        SetupLayout            matlab.ui.container.GridLayout
        SetupFooterLayout      matlab.ui.container.GridLayout
        FrameSize              matlab.ui.control.Label
        NextFrame2             matlab.ui.control.Button
        BackFrame2             matlab.ui.control.Button
        FinalTime              matlab.ui.control.NumericEditField
        BackFrame1             matlab.ui.control.Button
        InitialTime            matlab.ui.control.NumericEditField
        NextFrame1             matlab.ui.control.Button
        SetupHeaderLayout      matlab.ui.container.GridLayout
        PixelNeighbors         matlab.ui.control.NumericEditField
        PixelNeighborsLabel    matlab.ui.control.Label
        DetectionAlgorithm     matlab.ui.control.DropDown
        InitAlgLabel           matlab.ui.control.Label
        MaxPoints              matlab.ui.control.NumericEditField
        MaxPointsLabel         matlab.ui.control.Label
        AutoReInit             matlab.ui.control.CheckBox
        AutoGridWidth          matlab.ui.control.NumericEditField
        AutoGridHeight         matlab.ui.control.NumericEditField
        AutoGridLabel          matlab.ui.control.Label
        PlayButton             matlab.ui.control.Button
        ClearRegion            matlab.ui.control.Button
        AutoGridRegions        matlab.ui.control.Button
        SelectRegion           matlab.ui.control.Button
        CropVideo              matlab.ui.control.Button
        LoadVideo              matlab.ui.control.Button
        InitialFrame           matlab.ui.control.UIAxes
        FinalFrame             matlab.ui.control.UIAxes
        IntensityTab           matlab.ui.container.Tab
        IntensityTabLayout     matlab.ui.container.GridLayout
        IntensityHeaderLayout  matlab.ui.container.GridLayout
        SmoothingFactor        matlab.ui.control.NumericEditField
        SmoothingFactorLabel   matlab.ui.control.Label
        nGroups                matlab.ui.control.NumericEditField
        GroupsLabel            matlab.ui.control.Label
        SaveButton             matlab.ui.control.Button
        ToggleLegend           matlab.ui.control.StateButton
        IntensityHistories     matlab.ui.control.UIAxes
        iFrameFinal2           matlab.ui.control.UIAxes
        IntensityRateTab       matlab.ui.container.Tab
        RateTabLayout          matlab.ui.container.GridLayout
        IntensityRate          matlab.ui.control.UIAxes
        iFrameFinal3           matlab.ui.control.UIAxes
        GridLabelingTab        matlab.ui.container.Tab
        LabelingTabLayout      matlab.ui.container.GridLayout
        LabelTable             matlab.ui.control.Table
        LabelingHeaderLayout   matlab.ui.container.GridLayout
        LabelRegion            matlab.ui.control.Button
        ClearLabel             matlab.ui.control.Button
        RefreshPlots           matlab.ui.control.Button
        SaveLabels             matlab.ui.control.Button
        Label                  matlab.ui.control.Label
        LabelEditField         matlab.ui.control.EditField
        LabeledFrame           matlab.ui.control.UIAxes
    end

    
    % Copyright 2020 - 2026 The MathWorks, Inc.
       
    properties (Access = private)
        IsOnline
        Angio  % IntensityTracker object
        Video  % VideoReader object
        cframe        % initial colour frame
        region = [];  % initial regions
        points = [];  % initial points
        nPoints = [];
        Igroup
        Ismooth
        dIdtGroup
        groupIds
        GridEnabled = false
        nRows
        nCols
        gridRows
        gridCols
        gridIds = {}
        gridLabels = {}
        nGrpPrev
        hLines2
        hLines3
        hLegend2
        cmap
    end
    
    % Methods in external files
    methods (Access = private)
        setupButtonIcons(app);
        resetComponents(app)
        refreshLabelTab(app)
        onSaveButtonPushed(app,flag)
        onLoadVideoPushed(app)
        onCropVideoPushed(app)
        onInitialTimeChanged(app)
        onFinalTimeChanged(app)
        onSelectRegionPushed(app)
        onAutoGridRegionsPushed(app)
        onClearRegionPushed(app)
        onPlayButtonPushed(app)
        onGroupsValueChanged(app)
        onSmoothingValueChanged(app)
        onLabelRegionPushed(app)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Check if running in MATLAB Online
            try
                % "functionality is not available on remote platforms"
                vision.DeployableVideoPlayer;
                app.IsOnline = false;
            catch ME
                % DeployableVideoPlayer will error in MATLAB Online
                app.IsOnline = true;
            end

            % Set app figure icon and resize figure
            app.UIFigure.Icon = "FluorescentBulb.png";
            if ~app.IsOnline
                app.UIFigure.Units = "normalized";
                app.UIFigure.Position = [0.17,0.17,0.66,0.66];
                app.UIFigure.Units = "pixels";
            end
            
            % Set app font and button icons
            objs = findobj( app.UIFigure, "-property", "FontName" );
            set( objs, FontName="Segoe UI" );
            setupButtonIcons(app);

            % Disable VideoWriter warning (re-enable on app close)
            warning("off","MATLAB:audiovideo:VideoWriter:noFramesWritten")
            warning("off","MATLAB:audiovideo:VideoWriter:mp4FramePadded")
        end

        % Button pushed function: LoadVideo
        function LoadVideoPushed(app, event)
            onLoadVideoPushed(app);
        end

        % Button pushed function: CropVideo
        function CropVideoPushed(app, event)
            onCropVideoPushed(app);
        end

        % Button pushed function: SelectRegion
        function SelectRegionPushed(app, event)
            onSelectRegionPushed(app);
        end

        % Button pushed function: AutoGridRegions
        function AutoGridRegionsPushed(app, event)
            onAutoGridRegionsPushed(app);
        end

        % Button pushed function: ClearRegion
        function ClearRegionPushed(app, event)
            onClearRegionPushed(app);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            onPlayButtonPushed(app);
        end

        % Value changed function: InitialTime
        function InitialTimeChanged(app, event)
            onInitialTimeChanged(app);
        end

        % Button pushed function: BackFrame1
        function BackFrame1Pushed(app, event)
            app.InitialTime.Value = app.InitialTime.Value - 1/app.Video.FrameRate;
            onInitialTimeChanged(app);
        end

        % Button pushed function: NextFrame1
        function NextFrame1Pushed(app, event)
            app.InitialTime.Value = app.InitialTime.Value + 1/app.Video.FrameRate;
            onInitialTimeChanged(app);
        end

        % Value changed function: FinalTime
        function FinalTimeChanged(app, event)
            onFinalTimeChanged(app);
        end

        % Button pushed function: BackFrame2
        function BackFrame2Pushed(app, event)
            app.FinalTime.Value = app.FinalTime.Value - 1/app.Video.FrameRate;
            onFinalTimeChanged(app);
        end

        % Button pushed function: NextFrame2
        function NextFrame2Pushed(app, event)
            app.FinalTime.Value = app.FinalTime.Value + 1/app.Video.FrameRate;
            onFinalTimeChanged(app);            
        end

        % Value changed function: ToggleLegend
        function ToggleLegendPushed(app, event)
            if isempty(app.hLegend2)
                return
            end
            if app.ToggleLegend.Value
                app.hLegend2.Visible = "on";
            else
                app.hLegend2.Visible = "off";
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            onSaveButtonPushed(app,"all");
        end

        % Value changed function: nGroups
        function nGroupsValueChanged(app, event)
            onGroupsValueChanged(app);
            onSmoothingValueChanged(app);
        end

        % Value changed function: SmoothingFactor
        function SmoothingFactorValueChanged(app, event)
            onSmoothingValueChanged(app);
        end

        % Button pushed function: LabelRegion
        function LabelRegionButtonPushed(app, event)
            onLabelRegionPushed(app);
        end

        % Button pushed function: ClearLabel
        function ClearLabelButtonPushed(app, event)
            % Clear most recent set of grid labels
            app.gridIds(end) = [];
            app.gridLabels(end) = [];

            % Update figure and tbl
            refreshLabelTab(app)
            if (numel(app.gridIds) == 0)
                app.ClearLabel.Enable = "off";
                app.SaveLabels.Enable = "off";
            end
        end

        % Button pushed function: RefreshPlots
        function RefreshPlotsButtonPushed(app, event)
            app.nGroups.Value = 0;
            app.onGroupsValueChanged();
            app.onSmoothingValueChanged();
            app.TabGroup.SelectedTab = app.IntensityTab;
        end

        % Button pushed function: SaveLabels
        function SaveLabelsButtonPushed(app, event)
            onSaveButtonPushed(app,"labels");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            warning("on","MATLAB:audiovideo:VideoWriter:noFramesWritten")
            warning("on","MATLAB:audiovideo:VideoWriter:mp4FramePadded")
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Fluorescence Tracker';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.SetupLayout = uigridlayout(app.SetupTab);
            app.FinalFrame = uiaxes(app.SetupLayout);
            title(app.FinalFrame, 'Final Frame')
            app.FinalFrame.XTick = [];
            app.FinalFrame.XTickLabelRotationMode = 'manual';
            app.FinalFrame.XTickLabel = '';
            app.FinalFrame.YTick = [];
            app.FinalFrame.YTickLabelRotationMode = 'manual';
            app.FinalFrame.YTickLabel = '';
            app.FinalFrame.ZTickLabelRotationMode = 'manual';
            app.InitialFrame = uiaxes(app.SetupLayout);
            title(app.InitialFrame, 'Initial Frame')
            app.InitialFrame.XTick = [];
            app.InitialFrame.XTickLabelRotationMode = 'manual';
            app.InitialFrame.YTick = [];
            app.InitialFrame.YTickLabelRotationMode = 'manual';
            app.InitialFrame.ZTickLabelRotationMode = 'manual';
            app.SetupHeaderLayout = uigridlayout(app.SetupLayout);
            app.LoadVideo = uibutton(app.SetupHeaderLayout, 'push');
            app.LoadVideo.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoPushed, true);
            app.LoadVideo.Tooltip = 'Load Video';
            app.LoadVideo.Text = '';
            app.CropVideo = uibutton(app.SetupHeaderLayout, 'push');
            app.CropVideo.ButtonPushedFcn = createCallbackFcn(app, @CropVideoPushed, true);
            app.CropVideo.Enable = 'off';
            app.CropVideo.Tooltip = 'Trim and Save Video';
            app.CropVideo.Text = '';
            app.SelectRegion = uibutton(app.SetupHeaderLayout, 'push');
            app.SelectRegion.ButtonPushedFcn = createCallbackFcn(app, @SelectRegionPushed, true);
            app.SelectRegion.Enable = 'off';
            app.SelectRegion.Tooltip = 'Select Region';
            app.SelectRegion.Text = '';
            app.AutoGridRegions = uibutton(app.SetupHeaderLayout, 'push');
            app.AutoGridRegions.ButtonPushedFcn = createCallbackFcn(app, @AutoGridRegionsPushed, true);
            app.AutoGridRegions.Enable = 'off';
            app.AutoGridRegions.Tooltip = 'Auto-Grid Regions';
            app.AutoGridRegions.Text = '';
            app.ClearRegion = uibutton(app.SetupHeaderLayout, 'push');
            app.ClearRegion.ButtonPushedFcn = createCallbackFcn(app, @ClearRegionPushed, true);
            app.ClearRegion.Enable = 'off';
            app.ClearRegion.Tooltip = 'Clear Regions';
            app.ClearRegion.Text = '';
            app.PlayButton = uibutton(app.SetupHeaderLayout, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Tooltip = 'Run and Track Points';
            app.PlayButton.Text = '';
            app.AutoGridLabel = uilabel(app.SetupHeaderLayout);
            app.AutoGridLabel.Text = 'Auto-Grid Size:';
            app.AutoGridHeight = uieditfield(app.SetupHeaderLayout, 'numeric');
            app.AutoGridHeight.Limits = [1 36];
            app.AutoGridHeight.RoundFractionalValues = 'on';
            app.AutoGridHeight.Tooltip = 'Auto-Grid Rows';
            app.AutoGridHeight.Value = 12;
            app.AutoGridWidth = uieditfield(app.SetupHeaderLayout, 'numeric');
            app.AutoGridWidth.Limits = [1 48];
            app.AutoGridWidth.RoundFractionalValues = 'on';
            app.AutoGridWidth.Tooltip = 'Auto-Grid Columns';
            app.AutoGridWidth.Value = 16;
            app.AutoReInit = uicheckbox(app.SetupHeaderLayout);
            app.AutoReInit.Tooltip = 'Automatic Point Re-Initialization';
            app.AutoReInit.Text = 'Auto-ReInit';
            app.MaxPointsLabel = uilabel(app.SetupHeaderLayout);
            app.MaxPointsLabel.Text = 'Max Initial Pts.:';
            app.MaxPoints = uieditfield(app.SetupHeaderLayout, 'numeric');
            app.MaxPoints.Limits = [3 Inf];
            app.MaxPoints.RoundFractionalValues = 'on';
            app.MaxPoints.Tooltip = 'Max Points per Selection';
            app.MaxPoints.Value = 20;
            app.InitAlgLabel = uilabel(app.SetupHeaderLayout);
            app.InitAlgLabel.Text = 'Detection Alg.:';
            app.DetectionAlgorithm = uidropdown(app.SetupHeaderLayout);
            app.DetectionAlgorithm.Items = {'MinEigen', 'BRISK', 'FAST', 'Harris', 'KAZE', 'SURF'};
            app.DetectionAlgorithm.ItemsData = {'detectMinEigenFeatures', 'detectBRISKFeatures', 'detectFASTFeatures', 'detectHarrisFeatures', 'detectKAZEFeatures', 'detectSURFFeatures'};
            app.DetectionAlgorithm.Tooltip = 'Initial Points Detection Algorithm';
            app.DetectionAlgorithm.Value = 'detectMinEigenFeatures';
            app.PixelNeighborsLabel = uilabel(app.SetupHeaderLayout);
            app.PixelNeighborsLabel.Text = 'Pixel Neighbors:';
            app.PixelNeighbors = uieditfield(app.SetupHeaderLayout, 'numeric');
            app.PixelNeighbors.Limits = [0 Inf];
            app.PixelNeighbors.Tooltip = 'Include Pixels Up-Down-Left-Right';
            app.PixelNeighbors.Value = 5;
            app.SetupFooterLayout = uigridlayout(app.SetupLayout);
            app.NextFrame1 = uibutton(app.SetupFooterLayout, 'push');
            app.NextFrame1.ButtonPushedFcn = createCallbackFcn(app, @NextFrame1Pushed, true);
            app.NextFrame1.Enable = 'off';
            app.NextFrame1.Tooltip = 'Next Frame';
            app.NextFrame1.Text = '';
            app.InitialTime = uieditfield(app.SetupFooterLayout, 'numeric');
            app.InitialTime.Limits = [0 Inf];
            app.InitialTime.ValueDisplayFormat = '%11.5g';
            app.InitialTime.ValueChangedFcn = createCallbackFcn(app, @InitialTimeChanged, true);
            app.InitialTime.Enable = 'off';
            app.InitialTime.Tooltip = 'Initial Time (sec)';
            app.BackFrame1 = uibutton(app.SetupFooterLayout, 'push');
            app.BackFrame1.ButtonPushedFcn = createCallbackFcn(app, @BackFrame1Pushed, true);
            app.BackFrame1.Enable = 'off';
            app.BackFrame1.Tooltip = 'Previous Frame';
            app.BackFrame1.Text = '';
            app.FinalTime = uieditfield(app.SetupFooterLayout, 'numeric');
            app.FinalTime.Limits = [0 Inf];
            app.FinalTime.ValueDisplayFormat = '%11.5g';
            app.FinalTime.ValueChangedFcn = createCallbackFcn(app, @FinalTimeChanged, true);
            app.FinalTime.Enable = 'off';
            app.FinalTime.Tooltip = 'Final Time (sec)';
            app.BackFrame2 = uibutton(app.SetupFooterLayout, 'push');
            app.BackFrame2.ButtonPushedFcn = createCallbackFcn(app, @BackFrame2Pushed, true);
            app.BackFrame2.Enable = 'off';
            app.BackFrame2.Tooltip = 'Previous Frame';
            app.BackFrame2.Text = '';
            app.NextFrame2 = uibutton(app.SetupFooterLayout, 'push');
            app.NextFrame2.ButtonPushedFcn = createCallbackFcn(app, @NextFrame2Pushed, true);
            app.NextFrame2.Enable = 'off';
            app.NextFrame2.Tooltip = 'Next Frame';
            app.NextFrame2.Text = '';
            app.FrameSize = uilabel(app.SetupFooterLayout);
            app.FrameSize.Text = '';
            app.IntensityTab = uitab(app.TabGroup);
            app.IntensityTab.Title = 'Fluorescence Intensity';
            app.IntensityTabLayout = uigridlayout(app.IntensityTab);
            app.iFrameFinal2 = uiaxes(app.IntensityTabLayout);
            title(app.iFrameFinal2, 'Final Frame')
            app.iFrameFinal2.XTick = [];
            app.iFrameFinal2.XTickLabelRotationMode = 'manual';
            app.iFrameFinal2.YTick = [];
            app.iFrameFinal2.YTickLabelRotationMode = 'manual';
            app.iFrameFinal2.ZTickLabelRotationMode = 'manual';
            app.IntensityHistories = uiaxes(app.IntensityTabLayout);
            title(app.IntensityHistories, 'Point and Group Intensity Time Histories')
            xlabel(app.IntensityHistories, 'Video Time (sec)')
            ylabel(app.IntensityHistories, 'Fluorescence Intensity  [ 0 - 255 ]')
            app.IntensityHistories.XTickLabelRotationMode = 'manual';
            app.IntensityHistories.YTickLabelRotationMode = 'manual';
            app.IntensityHistories.ZTickLabelRotationMode = 'manual';
            app.IntensityHeaderLayout = uigridlayout(app.IntensityTabLayout);
            app.ToggleLegend = uibutton(app.IntensityHeaderLayout, 'state');
            app.ToggleLegend.ValueChangedFcn = createCallbackFcn(app, @ToggleLegendPushed, true);
            app.ToggleLegend.Tooltip = 'Toggle Legend On/Off';
            app.ToggleLegend.Enable = 'off';
            app.ToggleLegend.Text = '';
            app.ToggleLegend.Value = true;
            app.SaveButton = uibutton(app.IntensityHeaderLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Tooltip = 'Save Results';
            app.SaveButton.Text = '';
            app.GroupsLabel = uilabel(app.IntensityHeaderLayout);
            app.GroupsLabel.Text = 'Number of Groups:';
            app.nGroups = uieditfield(app.IntensityHeaderLayout, 'numeric');
            app.nGroups.Limits = [0 Inf];
            app.nGroups.RoundFractionalValues = 'on';
            app.nGroups.ValueDisplayFormat = '%3g';
            app.nGroups.ValueChangedFcn = createCallbackFcn(app, @nGroupsValueChanged, true);
            app.nGroups.Enable = 'off';
            app.nGroups.Tooltip = 'Number of Groups';
            app.SmoothingFactorLabel = uilabel(app.IntensityHeaderLayout);
            app.SmoothingFactorLabel.Text = 'Smoothing Factor:';
            app.SmoothingFactor = uieditfield(app.IntensityHeaderLayout, 'numeric');
            app.SmoothingFactor.Limits = [0 1];
            app.SmoothingFactor.ValueChangedFcn = createCallbackFcn(app, @SmoothingFactorValueChanged, true);
            app.SmoothingFactor.Enable = 'off';
            app.SmoothingFactor.Tooltip = 'Smoothing Factor';
            app.SmoothingFactor.Value = 0.1;
            app.IntensityRateTab = uitab(app.TabGroup);
            app.IntensityRateTab.Title = 'Intensity Rate';
            app.RateTabLayout = uigridlayout(app.IntensityRateTab);
            app.iFrameFinal3 = uiaxes(app.RateTabLayout);
            title(app.iFrameFinal3, 'Final Frame')
            app.iFrameFinal3.XTick = [];
            app.iFrameFinal3.XTickLabelRotationMode = 'manual';
            app.iFrameFinal3.YTick = [];
            app.iFrameFinal3.YTickLabelRotationMode = 'manual';
            app.iFrameFinal3.ZTickLabelRotationMode = 'manual';
            app.IntensityRate = uiaxes(app.RateTabLayout);
            title(app.IntensityRate, 'Point and Group Intensity Time Rate of Change')
            xlabel(app.IntensityRate, 'Video Time (sec)')
            ylabel(app.IntensityRate, 'Fluorescence Intensity Change per Time (\DeltaI/\Deltat)')
            app.IntensityRate.XTickLabelRotationMode = 'manual';
            app.IntensityRate.YTickLabelRotationMode = 'manual';
            app.IntensityRate.ZTickLabelRotationMode = 'manual';
            app.GridLabelingTab = uitab(app.TabGroup);
            app.GridLabelingTab.Title = 'Grid Labeling';
            app.LabelingTabLayout = uigridlayout(app.GridLabelingTab);
            app.LabeledFrame = uiaxes(app.LabelingTabLayout);
            title(app.LabeledFrame, 'Initial Frame')
            app.LabeledFrame.XTick = [];
            app.LabeledFrame.XTickLabelRotationMode = 'manual';
            app.LabeledFrame.YTick = [];
            app.LabeledFrame.YTickLabelRotationMode = 'manual';
            app.LabeledFrame.ZTickLabelRotationMode = 'manual';
            app.LabelingHeaderLayout = uigridlayout(app.LabelingTabLayout);
            app.LabelEditField = uieditfield(app.LabelingHeaderLayout, 'text');
            app.Label = uilabel(app.LabelingHeaderLayout);
            app.Label.Text = 'Label:';
            app.SaveLabels = uibutton(app.LabelingHeaderLayout, 'push');
            app.SaveLabels.ButtonPushedFcn = createCallbackFcn(app, @SaveLabelsButtonPushed, true);
            app.SaveLabels.Enable = 'off';
            app.SaveLabels.Tooltip = 'Save Labels';
            app.SaveLabels.Text = '';
            app.RefreshPlots = uibutton(app.LabelingHeaderLayout, 'push');
            app.RefreshPlots.ButtonPushedFcn = createCallbackFcn(app, @RefreshPlotsButtonPushed, true);
            app.RefreshPlots.Enable = 'off';
            app.RefreshPlots.Tooltip = 'Refresh Plots';
            app.RefreshPlots.Text = '';
            app.ClearLabel = uibutton(app.LabelingHeaderLayout, 'push');
            app.ClearLabel.ButtonPushedFcn = createCallbackFcn(app, @ClearLabelButtonPushed, true);
            app.ClearLabel.Enable = 'off';
            app.ClearLabel.Tooltip = 'Clear Label';
            app.ClearLabel.Text = '';
            app.LabelRegion = uibutton(app.LabelingHeaderLayout, 'push');
            app.LabelRegion.ButtonPushedFcn = createCallbackFcn(app, @LabelRegionButtonPushed, true);
            app.LabelRegion.Enable = 'off';
            app.LabelRegion.Tooltip = 'Label Region';
            app.LabelRegion.Text = '';
            app.LabelTable = uitable(app.LabelingTabLayout);
            app.LabelTable.ColumnName = {'Selection'; 'Grid Region'; 'Label'};
            app.LabelTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FluorescenceTracker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end