classdef AnomalyClassifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        LoadButton  matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        model
        dTdecay
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load and extract classification model
            load( "KnnClassifier.mat", "trainedModel" )
            flds = fields( trainedModel );
            idx = contains( flds, "Classification" );
            app.model = trainedModel.(flds{idx});

            % Extract decay times from model predictors
            preds = string( app.model.PredictorNames );
            preds = preds( contains( preds, "Idecay" ) );
            app.dTdecay = str2double( extractBetween( preds, "Idecay", "s" ) );
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [fileName,filePath] = uigetfile( "*.mp4" );

            if isequal( fileName, 0 )
                return % user canceled file selection
            end
            figure( app.UIFigure ) % bring app to foreground
            app.LoadButton.Enable = "off";
            
            % Potential App Inputs
            tInit  = 1/30; % seconds
            tFinal = 200;  % seconds
            maxPts = 30;   % max tracking points
            nRows  = 18;   % grid regions rows
            nCols  = 24;   % grid region columns

            % Setup Video Reader
            video = VideoReader( fullfile( filePath, fileName ) );
            frame = readFrame( video );
            w = video.Width;
            h = video.Height/2;

            % Display Initial Frame
            cframe = imcrop( frame, [0 0 w h] );
            iframe = imcrop( frame, [0 h+1 w h] );
            iframe = im2gray( iframe );
            imshow( cframe, Parent=app.UIAxes )

            % Detect Control Points and Initialize Point Tracker
            pts = detectMinEigenFeatures( im2gray( cframe ), ...
                ROI=[0.05*[w h] 0.9*[w h]] ); % set ROI away from frame edges
            pts = selectUniform( pts, maxPts, [h w] );
            points1 = pts.Location;
            tracker = vision.PointTracker( MaxBidirectionalError=1 );
            initialize( tracker, points1, cframe );

            % Setup a Grid of Regions
            nRegs = nRows*nCols;      % number of grid regions
            hReg  = ceil( h/nRows );  % height of each grid region
            wReg  = ceil( w/nCols );  %  width of each grid region
            [X,Y] = meshgrid( 0.5+(0:(nCols-1))*wReg, 0.5+(0:(nRows-1))*hReg );
            region = [X(:) Y(:) min(wReg,w+0.5-X(:)) min(hReg,h+0.5-Y(:))];

            % Compute Grid Region Initial Intensities
            Igrid = imresize( single( iframe ), [nRows nCols], "box" );

            % Pre-allocate memory to store results
            nFrames = ceil( (tFinal-tInit)*video.FrameRate );
            time = NaN( 1, nFrames );
            Iregion = NaN( [[nRows nCols] nFrames], "single" );
            time(1) = tInit;
            Iregion(:,:,1) = Igrid;
            OV = imref2d( [h w] );
            cframe1 = cframe;

            useGpu = canUseGPU;
            if useGpu
                Iregion = gpuArray( Iregion );
            end

            % Process Video to Compute Intensity Time Histories
            k = 1;
            while hasFrame( video ) && (video.CurrentTime <= tFinal)
                k = k + 1;
                time(k) = video.CurrentTime;

                % 1) Read and separate next video frame
                frame  = readFrame( video );
                cframe = imcrop( frame, [0 0 w h] );   % color frame (top half)
                iframe = imcrop( frame, [0 1+h w h] ); % intensity frame (bottom half)
                iframe = im2gray( iframe );

                % 2) Track points from frame to frame
                [points,pval] = tracker( cframe );

                % 3) Compute corresponding geometric transformation
                if (nnz(pval) < 3) % requires at least 3 valid points
                    k = k - 1;
                    break
                end
                tform = fitgeotform2d( points(pval,:), points1(pval,:), "affine" );

                if useGpu
                    % Store arrays on GPU
                    cframe = gpuArray( cframe );
                    iframe = gpuArray( iframe );
                end

                % 4) Warp current frame to align with initial frame
                creg = imwarp( cframe, tform, OutputView=OV );
                ireg = imwarp( iframe, tform, OutputView=OV );

                % 5) Compute grid region intensity using 2-D convolution
                Igrid = single( ireg );      % NaN not allowed for uint8
                Igrid(all(creg==0,3)) = NaN; % ignore black padding due to warping
                Iregion(:,:,k) = imresize( Igrid, [nRows nCols], "box" );

                if useGpu
                    % Transfer data back to CPU (for insertMarker)
                    creg = gather( creg );
                end

                % Mark color and intensity frames with tracked points
                % Note, transformed points will appear relatively fixed
                xy = transformPointsForward( tform, points(pval,:) );
                app.UIAxes.Children.CData = insertMarker( creg, xy );
                drawnow limitrate
            end

            % Clean-up
            release( tracker )
            time = time(1:k);
            Iregion = reshape( Iregion(:,:,1:k), nRegs, k );
            if useGpu
                % Transfer data back to CPU for smoothdata
                Iregion = gather( Iregion );
            end

            % Extract class names
            classNames = string( app.model.ClassNames );
            cmap = turbo; % color map: Negative -> blue, Cancer -> red
            if (classNames(1) == "Cancer")
                cmap = flip( cmap );
            end

            % Use Scores to Create Prediction Heatmap

            % 1) Reduce noise in intensity time history for each grid region
            Ismooth = smoothdata( Iregion, 2, SmoothingFactor=0.1 );

            % 2) Extract features for each grid region
            features = getPredictors( time, Ismooth, app.dTdecay );
            val = ~any( isnan( features.Variables ), 2 );
            features = features(val,:); % only use valid regions

            % 3) Compute classification scores for each grid region
            [~,score] = predict( app.model, features );

            % 4) Interpolate scores from grids to pixels and map values to colors
            overlay = predictionOverlay( cframe1, region, val, score, Colormap=cmap );

            % Display overlay
            app.UIAxes.Children.CData = overlay;
            colormap( app.UIAxes, cmap )
            clim( app.UIAxes, [-1 1] )
            cbar = colorbar( app.UIAxes, "south", Ticks=[-1 1] );
            cbar.TickLabels = classNames;
            cbar.Position(2) = 0.05;

            app.LoadButton.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnomalyClassifier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end